classdef SPIM_ProcessApp_R2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPIM_Process                 matlab.ui.Figure
        RemoveShakeCheckBox          matlab.ui.control.CheckBox
        MaxPixelShiftSliderLabel     matlab.ui.control.Label
        MaxPixelShiftSlider          matlab.ui.control.Slider
        RemoveStripesCheckBox        matlab.ui.control.CheckBox
        FilesTable                   matlab.ui.control.Table
        SelectFilesButton            matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        MaxPixelEdit                 matlab.ui.control.NumericEditField
        GaborPlot                    matlab.ui.control.UIAxes
        SigmaXEditFieldLabel         matlab.ui.control.Label
        SigmaXEditField              matlab.ui.control.NumericEditField
        SigmaYEditFieldLabel         matlab.ui.control.Label
        SigmaYEditField              matlab.ui.control.NumericEditField
        TestGaborButton              matlab.ui.control.Button
        ProcessFilesButton           matlab.ui.control.Button
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxItersEdit                 matlab.ui.control.NumericEditField
        PrecisionfractionofpxEditFieldLabel  matlab.ui.control.Label
        PrecisionEdit                matlab.ui.control.NumericEditField
        zSlider                      matlab.ui.control.Slider
        DataPlot                     matlab.ui.control.UIAxes
        ThetadegLabel                matlab.ui.control.Label
        ThetaEdit                    matlab.ui.control.NumericEditField
        UpdatingGaborLabel           matlab.ui.control.Label
    end


    properties (Access = public)
        gb_im double % gabor image used for display
        gbf double % for padding to real use images
        im double % image to pad the gabor
        file_index % index of selected file in table
        im_hght = 1024;
        im_wid = 1344;
        data_wid
        data_hght
        filename
        pathname
    end


    methods (Access = private)

        % Code that executes after component creation
        function SPIM_ProcessStartup(app)
            % add drawing of gabor function with default settings here
            % ...
            app.im = zeros(app.im_hght/4,app.im_wid/2,'double');
            app.gbf = gabor_expfn(app.SigmaXEditField.Value,app.SigmaYEditField.Value,app.ThetaEdit.Value);
            app.gb_im = fftshift(ZeroAdd(app.gbf,app.im));
            app.GaborPlot.XLim = [1,round(app.im_wid/2)];
            app.GaborPlot.YLim = [1,round(app.im_hght/4)];
            haxes = app.GaborPlot;
            imagesc(app.gb_im,'Parent',haxes);
            
        end

        % Button pushed function: SelectFilesButton
        function SPIM_SelectFilesButPress(app, event)
            %% Get tifs files to register from dialog
            [app.filename, app.pathname] = uigetfile( ...
            {  '*.tif;*.tiff','Tiff Files (*.tif, *.tiff)'; ...
               '*.*',  'All Files (*.*)'}, ...
               'Select Tif files to register...', ...
               'MultiSelect', 'on', 'R:\User Data');
           if app.pathname == 0
               return
           end
           
           if ~iscell(app.filename)
               app.filename = cellstr(app.filename);
           end
           
           fstrlens = cellfun(@length, app.filename);
           fstrmax = max(fstrlens);
           app.FilesTable.ColumnWidth = {round(fstrmax * 7.5)};
           app.FilesTable.Data = app.filename.';
        end

        % Value changed function: MaxPixelEdit
        function SPIM_MaxPixelEditChanged(app, event)
            value = app.MaxPixelEdit.Value;
            if (value > app.MaxPixelShiftSlider.Limits(2)) % greater than max limit
                value = app.MaxPixelShiftSlider.Limits(2);
                app.MaxPixelEdit.Value = value;
                app.MaxPixelShiftSlider.Value = value;
            elseif (value < app.MaxPixelShiftSlider.Limits(1)) % less than min limit
                value = app.MaxPixelShiftSlider.Limits(1);
                app.MaxPixelEdit.Value = value;
                app.MaxPixelShiftSlider.Value = value;
            end
            app.MaxPixelShiftSlider.Value = value;
        end

        % Value changed function: MaxPixelShiftSlider
        function SPIM_MaxPixShiftChanged(app, event)
            value = app.MaxPixelShiftSlider.Value;
            app.MaxPixelEdit.Value = value;
        end

        % Callback function: SigmaXEditField, SigmaYEditField, 
        % ThetaEdit
        function SPIM_DrawGabor(app, event)
            app.UpdatingGaborLabel.Text = "Updating...";
            app.gbf = gabor_expfn(app.SigmaXEditField.Value,app.SigmaYEditField.Value,app.ThetaEdit.Value);
            app.gb_im = fftshift(ZeroAdd(app.gbf,app.im));
            haxes = app.GaborPlot;
            imagesc(app.gb_im,'Parent',haxes);
            app.UpdatingGaborLabel.Text = "";
        end

        % Button pushed function: TestGaborButton
        function SPIM_TestGaborButPress(app, event)
            % app.FilesTable.get
            if (app.file_index > 0)
                %% Open tif, get details, setup outputs
                file = [app.pathname app.filename{1,app.file_index}];
                tif_info = imfinfo(file);
                width = tif_info(1).Width;
                height = tif_info(1).Height;
                zn = size(tif_info,1);
                zmid = ceil(zn/2);
                img = imread(file,round(app.zSlider.Value));
                wid3rd = floor(width/3);
                hght3rd = floor(height/3);
                img3rd = imcrop(img,[wid3rd hght3rd wid3rd hght3rd]);
                origfig = figure(1);
                origfig.NumberTitle = 'off';
                origfig.Name = 'Original Image';
                imagesc(img3rd);
                
                psi = ZeroAdd(app.gbf,img3rd);
                psi = 1e-2*psi/max(psi(:)); %Normalization
                u = VSNR_Destripe(double(img3rd),psi,app.MaxItersEdit.Value);
                
                dnfig = figure;
                dnfig.NumberTitle = 'off';
                dnfig.Name = 'Denoised Image';
                imagesc(u);
            end
        end

        % Cell selection callback: FilesTable
        function SPIM_TableSelection(app, event)
            app.file_index = event.Indices;
            if (size(app.file_index,1)>1)
                app.file_index = -1;
            else
                app.file_index = app.file_index(1);
                file = [app.pathname app.filename{1,app.file_index}];
                %app.DataPlot.Title = app.filename{1,app.file_index};
                tif_info = imfinfo(file);
                app.data_wid = tif_info(1).Width;
                app.data_hght = tif_info(1).Height;
                zn = size(tif_info,1);
                app.zSlider.Limits(2) = zn;
                img = imread(file,app.zSlider.Value);
                wid3rd = floor(app.data_wid/3);
                hght3rd = floor(app.data_hght/3);
                img3rd = imcrop(img,[wid3rd hght3rd wid3rd hght3rd]);
                app.DataPlot.XLim = [1,wid3rd];
                app.DataPlot.YLim = [1,hght3rd];
                h = app.DataPlot;
                imagesc(img3rd,'Parent',h);
            end
        end

        % Button pushed function: ProcessFilesButton
        function SPIM_ProcessButClick(app, event)
            % if destripe
            % pad psi image
            % setup function to loop through z of any given image and destripe
            tic;
            upscl = 1/app.PrecisionEdit.Value;
            maxoffsetx = app.MaxPixelEdit.Value;
            maxoffsety = maxoffsetx;
                        
            %% Setup output arrays etc.
            fn = size(app.filename,2);
            %[xabs, yabs, xrel, yrel]
            cx = cell(fn,1);  % cell array for xrel's
            cy = cell(fn,1);  % cell array for yrel's
            
             %%  Register images in parallel loop
            % stupid simple parfor progess
            % n=75;                                  
            % fprintf(1,'%s\n\n',repmat('.',1,n));
            % parfor i = 1:n
            %   pause(0.05)         % or whatever                  
            %   fprintf(1,'\b.\n'); % \b is backspace
            % end
            
            parfor fi = 1:fn
                % temp image used to size the gabor function into psi
                %  psi is passed to SPIM_reg_destripe function
                tmpimg = imread([app.pathname, app.filename{1,fi}],1);
                psi = ZeroAdd(app.gbf,tmpimg);
                % reg tif
                [~,~,xrel,yrel,err] = SPIM_reg_destripe( ...
                    logical(app.RemoveShakeCheckBox.Value), ...
                    logical(app.RemoveStripesCheckBox.Value), ...
                    [app.pathname, app.filename{1,fi}], ...
                    upscl, maxoffsetx, maxoffsety, ...
                    app.MaxItersEdit.Value, psi );
                % add *rel values to array
                cx{fi} = xrel;
                cy{fi} = yrel;
            end
            
            if logical(app.RemoveShakeCheckBox.Value)
                save([app.pathname, 'xrel_cell'],'cx');
                save([app.pathname, 'yrel_cell'],'cy');
                
                %% Save figure pngs
                figure('Visible','off');
                zn = size(cx{1,1},1);
                xx = 1:zn;
                
                for fi = 1:fn
                    cx_ = cx{fi,1};
                    cy_ = cy{fi,1};
                    xxt = xx.';
                    
                    plot(xxt,cx_,xxt,cy_);
                    [~, name, ~] = fileparts(app.filename{1,fi});
                    title(name);
                    saveas(gcf, [app.pathname, name,'.png'],'png');
                end
            end
            toc;
        end

        % Value changed function: zSlider
        function SPIM_zSliderChanged(app, event)
            value = app.zSlider.Value;
            file = [app.pathname app.filename{1,app.file_index}];
            app.zSlider.Value = round(value);
            wid3rd = floor(app.data_wid/3);
            hght3rd = floor(app.data_hght/3);
            img = imread(file,round(value));
            img3rd = imcrop(img,[wid3rd hght3rd wid3rd hght3rd]);
            app.DataPlot.XLim = [1,wid3rd];
            app.DataPlot.YLim = [1,hght3rd];
            h = app.DataPlot;
            imagesc(img3rd,'Parent',h);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SPIM_Process = uifigure;
            app.SPIM_Process.Name = 'SPIM Files Post Process';
            app.RemoveShakeCheckBox = uicheckbox(app.SPIM_Process);
            app.RemoveShakeCheckBox.Text = 'Remove Shake';
            app.RemoveShakeCheckBox.Value = true;
            app.MaxPixelShiftSliderLabel = uilabel(app.SPIM_Process);
            app.MaxPixelShiftSliderLabel.Text = 'Max Pixel Shift';
            app.MaxPixelShiftSlider = uislider(app.SPIM_Process);
            app.MaxPixelShiftSlider.Limits = [0 3];
            app.MaxPixelShiftSlider.ValueChangedFcn = createCallbackFcn(app, @SPIM_MaxPixShiftChanged, true);
            app.MaxPixelShiftSlider.MinorTicks = [];
            app.MaxPixelShiftSlider.Value = 1.5;
            app.RemoveStripesCheckBox = uicheckbox(app.SPIM_Process);
            app.RemoveStripesCheckBox.Text = 'Remove Stripes';
            app.RemoveStripesCheckBox.Value = true;
            app.FilesTable = uitable(app.SPIM_Process);
            app.FilesTable.ColumnName = {'Filename'};
            app.FilesTable.RowName = {};
            app.FilesTable.CellSelectionCallback = createCallbackFcn(app, @SPIM_TableSelection, true);
            app.SelectFilesButton = uibutton(app.SPIM_Process, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SPIM_SelectFilesButPress, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.Label = uilabel(app.SPIM_Process);
            app.Label.Text = '';
            app.MaxPixelEdit = uieditfield(app.SPIM_Process, 'numeric');
            app.MaxPixelEdit.ValueChangedFcn = createCallbackFcn(app, @SPIM_MaxPixelEditChanged, true);
            app.MaxPixelEdit.Value = 1.5;
            app.GaborPlot = uiaxes(app.SPIM_Process);
            title(app.GaborPlot, 'Gabor Function')
            app.GaborPlot.XLim = [0 1];
            app.GaborPlot.YLim = [0 1];
            app.SigmaXEditFieldLabel = uilabel(app.SPIM_Process);
            app.SigmaXEditFieldLabel.Text = 'SigmaX';
            app.SigmaXEditField = uieditfield(app.SPIM_Process, 'numeric');
            app.SigmaXEditField.Limits = [0 Inf];
            app.SigmaXEditField.ValueChangedFcn = createCallbackFcn(app, @SPIM_DrawGabor, true);
            app.SigmaXEditField.Value = 1;
            app.SigmaYEditFieldLabel = uilabel(app.SPIM_Process);
            app.SigmaYEditFieldLabel.Text = 'SigmaY';
            app.SigmaYEditField = uieditfield(app.SPIM_Process, 'numeric');
            app.SigmaYEditField.Limits = [0 Inf];
            app.SigmaYEditField.ValueChangedFcn = createCallbackFcn(app, @SPIM_DrawGabor, true);
            app.SigmaYEditField.Value = 40;
            app.TestGaborButton = uibutton(app.SPIM_Process, 'push');
            app.TestGaborButton.ButtonPushedFcn = createCallbackFcn(app, @SPIM_TestGaborButPress, true);
            app.TestGaborButton.Text = 'Test Gabor Function';
            app.ProcessFilesButton = uibutton(app.SPIM_Process, 'push');
            app.ProcessFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SPIM_ProcessButClick, true);
            app.ProcessFilesButton.Text = 'Process Files';
            app.MaxIterationsEditFieldLabel = uilabel(app.SPIM_Process);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxItersEdit = uieditfield(app.SPIM_Process, 'numeric');
            app.MaxItersEdit.Limits = [2 200];
            app.MaxItersEdit.Value = 30;
            app.PrecisionfractionofpxEditFieldLabel = uilabel(app.SPIM_Process);
            app.PrecisionfractionofpxEditFieldLabel.Text = 'Precision (fraction of px.)';
            app.PrecisionEdit = uieditfield(app.SPIM_Process, 'numeric');
            app.PrecisionEdit.Limits = [0.0001 1];
            app.PrecisionEdit.Value = 0.05;
            app.zSlider = uislider(app.SPIM_Process);
            app.zSlider.Limits = [1 99];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SPIM_zSliderChanged, true);
            app.zSlider.Value = 1;
            app.DataPlot = uiaxes(app.SPIM_Process);
            title(app.DataPlot, 'Title')
            app.DataPlot.XLim = [0 1];
            app.DataPlot.YLim = [0 1];
            app.ThetadegLabel = uilabel(app.SPIM_Process);
            app.ThetadegLabel.Text = 'Theta (deg)';
            app.ThetaEdit = uieditfield(app.SPIM_Process, 'numeric');
            app.ThetaEdit.Limits = [-360 360];
            app.ThetaEdit.ValueChangedFcn = createCallbackFcn(app, @SPIM_DrawGabor, true);
            app.UpdatingGaborLabel = uilabel(app.SPIM_Process);
            app.UpdatingGaborLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SPIM_ProcessApp_R2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SPIM_Process)

            % Execute the startup function
            runStartupFcn(app, @SPIM_ProcessStartup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPIM_Process)
        end
    end
end